classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Example1UIFigure       matlab.ui.Figure
        LoadButton             matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        GrayButton             matlab.ui.control.Button
        BWButton               matlab.ui.control.Button
        OriginalButton         matlab.ui.control.Button
        BrightnessSliderLabel  matlab.ui.control.Label
        BrightnessSlider       matlab.ui.control.Slider
    end

    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global image1
            [filename, pathname] = uigetfile({'*.jpg'},'File Selector');
            fullpathname = strcat(pathname,filename);
            image1 = imread(fullpathname);
            imshow(image1,'Parent',app.UIAxes);
        end

        % Button pushed function: GrayButton
        function GrayButtonPushed(app, event)
            global image1
            imshow(rgb2gray(image1),'Parent',app.UIAxes);
        end

        % Button pushed function: BWButton
        function BWButtonPushed(app, event)
            global image1
            imshow(im2bw(image1),'Parent',app.UIAxes);
        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
            global image1
            imshow(image1,'Parent',app.UIAxes);
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            global image1
            image_gray = rgb2gray(image1);
            new_image = image_gray*value;
            imshow(new_image,'Parent',app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Example1UIFigure = uifigure;
            app.Example1UIFigure.Name = 'Example 1';
            app.LoadButton = uibutton(app.Example1UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.UIAxes = uiaxes(app.Example1UIFigure);
            app.GrayButton = uibutton(app.Example1UIFigure, 'push');
            app.GrayButton.ButtonPushedFcn = createCallbackFcn(app, @GrayButtonPushed, true);
            app.GrayButton.Text = 'Gray';
            app.BWButton = uibutton(app.Example1UIFigure, 'push');
            app.BWButton.ButtonPushedFcn = createCallbackFcn(app, @BWButtonPushed, true);
            app.BWButton.Text = 'B/W';
            app.OriginalButton = uibutton(app.Example1UIFigure, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Text = 'Original';
            app.BrightnessSliderLabel = uilabel(app.Example1UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.Example1UIFigure);
            app.BrightnessSlider.Limits = [0 1];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Example1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Example1UIFigure)
        end
    end
end